classdef APP1_Clasificador < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Clasificador         matlab.ui.Figure
        Imagen_Original      matlab.ui.control.UIAxes
        Canal_R              matlab.ui.control.UIAxes
        Canal_G              matlab.ui.control.UIAxes
        Canal_B              matlab.ui.control.UIAxes
        Imagen_Segmentada    matlab.ui.control.UIAxes
        LBPCanal_R           matlab.ui.control.UIAxes
        LBPCanal_G           matlab.ui.control.UIAxes
        LBPCanal_B           matlab.ui.control.UIAxes
        Salida               matlab.ui.control.EditField
        DiagnsticoButton     matlab.ui.control.Button
        OpcionesButtonGroup  matlab.ui.container.ButtonGroup
        CargarImagenButton   matlab.ui.control.Button
        ProcesarButton       matlab.ui.control.Button
        ClasificarButton     matlab.ui.control.Button
        LimpiarButton        matlab.ui.control.Button
    end

    
properties (Access = public)
        I                           % Imagen Dermatoscopica Original
        Icrp                        % Imagen Recortada
        DataLBP                     % Caracteristicas LBP de los 3 canales RGB
        pathname                    % Ubicacion del archivo
    end
    
    methods (Access = public)
        
        %% Funcion que genera imagen LBP y extrae caracteristicas LBP
        
        function [ DataLBP , Image_lbp] = getLBP(~,I_G)
           
grayImage = I_G;
% Get the dimensions of the image.  numberOfColorBands should be = 1.
[rows , columns , ~] = size(grayImage);
% Preallocate/instantiate array for the local binary pattern.
localBinaryPatternImage1 = zeros(size(grayImage), 'uint8');
localBinaryPatternImage2 = zeros(size(grayImage), 'uint8');
localBinaryPatternImage3 = zeros(size(grayImage), 'uint8');
localBinaryPatternImage4 = zeros(size(grayImage), 'uint8');
localBinaryPatternImage5 = zeros(size(grayImage), 'uint8');
localBinaryPatternImage6 = zeros(size(grayImage), 'uint8');
localBinaryPatternImage7 = zeros(size(grayImage), 'uint8');
localBinaryPatternImage8 = zeros(size(grayImage), 'uint8');
 
for row = 2 : rows - 1
    for col = 2 : columns - 1
        centerPixel = grayImage(row, col);
        pixel7=grayImage(row-1, col-1) > centerPixel;
        pixel6=grayImage(row-1, col) > centerPixel;
        pixel5=grayImage(row-1, col+1) > centerPixel;
        pixel4=grayImage(row, col+1) > centerPixel;
        pixel3=grayImage(row+1, col+1) > centerPixel;
        pixel2=grayImage(row+1, col) > centerPixel;
        pixel1=grayImage(row+1, col-1) > centerPixel;
        pixel0=grayImage(row, col-1) > centerPixel;
        
        % Create LBP image with the starting, LSB pixel in the upper left.
        eightBitNumber = uint8(...
            pixel7 * 2^7 + pixel6 * 2^6 + ...
            pixel5 * 2^5 + pixel4 * 2^4 + ...
            pixel3 * 2^3 + pixel2 * 2^2 + ...
            pixel1 * 2 + pixel0);
        % Or you can use the built-in function bwpack(), which is somewhat simpler but a lot slower.
        %       eightBitNumber = uint8(bwpack([pixel0; pixel1; pixel2; pixel3; pixel4; pixel5; pixel6; pixel7]));
        localBinaryPatternImage1(row, col) = eightBitNumber;
        
        % Create LBP image with the starting, LSB pixel in the upper middle.
        eightBitNumber = uint8(...
            pixel6 * 2^7 + pixel5 * 2^6 + ...
            pixel5 * 2^4 + pixel3 * 2^4 + ...
            pixel3 * 2^2 + pixel1 * 2^2 + ...
            pixel0 * 2 + pixel7);
        % Or you can use the built-in function bwpack(), which is somewhat simpler but a lot slower.
        %       eightBitNumber = uint8(bwpack([pixel0; pixel1; pixel2; pixel3; pixel4; pixel5; pixel6; pixel7]));
        localBinaryPatternImage2(row, col) = eightBitNumber;
        
        % Create LBP image with the starting, LSB pixel in the upper right.
        eightBitNumber = uint8(...
            pixel5 * 2^7 + pixel4 * 2^6 + ...
            pixel3 * 2^5 + pixel2 * 2^4 + ...
            pixel1 * 2^3 + pixel0 * 2^2 + ...
            pixel7 * 2 + pixel6);
        % Or you can use the built-in function bwpack(), which is somewhat simpler but a lot slower.
        %       eightBitNumber = uint8(bwpack([pixel0; pixel1; pixel2; pixel3; pixel4; pixel5; pixel6; pixel7]));
        localBinaryPatternImage3(row, col) = eightBitNumber;
        
        % Create LBP image with the starting, LSB pixel in the center right.
        eightBitNumber = uint8(...
            pixel4 * 2^7 + pixel3 * 2^6 + ...
            pixel2 * 2^5 + pixel1 * 2^4 + ...
            pixel0 * 2^3 + pixel7 * 2^2 + ...
            pixel6 * 2 + pixel5);
        % Or you can use the built-in function bwpack(), which is somewhat simpler but a lot slower.
        %       eightBitNumber = uint8(bwpack([pixel0; pixel1; pixel2; pixel3; pixel4; pixel5; pixel6; pixel7]));
        localBinaryPatternImage4(row, col) = eightBitNumber;
        
        % Create LBP image with the starting, LSB pixel in the lower right.
        eightBitNumber = uint8(...
            pixel3 * 2^7 + pixel2 * 2^6 + ...
            pixel1 * 2^5 + pixel0 * 2^4 + ...
            pixel7 * 2^3 + pixel6 * 2^2 + ...
            pixel5 * 2 + pixel0);
        % Or you can use the built-in function bwpack(), which is somewhat simpler but a lot slower.
        %       eightBitNumber = uint8(bwpack([pixel0; pixel1; pixel2; pixel3; pixel4; pixel5; pixel6; pixel7]));
        localBinaryPatternImage5(row, col) = eightBitNumber;
        
        % Create LBP image with the starting, LSB pixel in the lower center.
        eightBitNumber = uint8(...
            pixel2 * 2^7 + pixel1 * 2^6 + ...
            pixel0 * 2^5 + pixel7 * 2^4 + ...
            pixel6 * 2^3 + pixel5 * 2^2 + ...
            pixel4 * 2 + pixel3);
        % Or you can use the built-in function bwpack(), which is somewhat simpler but a lot slower.
        %       eightBitNumber = uint8(bwpack([pixel0; pixel1; pixel2; pixel3; pixel4; pixel5; pixel6; pixel7]));
        localBinaryPatternImage6(row, col) = eightBitNumber;
        
        % Create LBP image with the starting, LSB pixel in the lower left.
        eightBitNumber = uint8(...
            pixel1 * 2^7 + pixel0 * 2^6 + ...
            pixel7 * 2^5 + pixel6 * 2^4 + ...
            pixel5 * 2^3 + pixel4 * 2^2 + ...
            pixel3 * 2 + pixel2);
        % Or you can use the built-in function bwpack(), which is somewhat simpler but a lot slower.
        %       eightBitNumber = uint8(bwpack([pixel0; pixel1; pixel2; pixel3; pixel4; pixel5; pixel6; pixel7]));
        localBinaryPatternImage7(row, col) = eightBitNumber;
        
        % Create LBP image with the starting, LSB pixel in the center left.
        eightBitNumber = uint8(...
            pixel0 * 2^7 + pixel7 * 2^6 + ...
            pixel6 * 2^5 + pixel5 * 2^4 + ...
            pixel4 * 2^3 + pixel3 * 2^2 + ...
            pixel2 * 2 + pixel1);
        % Or you can use the built-in function bwpack(), which is somewhat simpler but a lot slower.
        %       eightBitNumber = uint8(bwpack([pixel0; pixel1; pixel2; pixel3; pixel4; pixel5; pixel6; pixel7]));
        localBinaryPatternImage8(row, col) = eightBitNumber;
        
    end
end
 
% Outer layer of pixels will be zero because they didn't have 8 neighbors.
% So, to avoid a huge spike in the histogram at zero, replace the outer layer of pixels with the next closest layer.
localBinaryPatternImage1(1, :) = localBinaryPatternImage1(2, :);
localBinaryPatternImage1(end, :) = localBinaryPatternImage1(end-1, :);
localBinaryPatternImage1(:, 1) = localBinaryPatternImage1(:, 2);
localBinaryPatternImage1(:, end) = localBinaryPatternImage1(:, end-1);
 
%*****Devuelvo Valores LBP*******
Image_lbp = localBinaryPatternImage1;
DataLBP= extractLBPFeatures(I_G,'NumNeighbors',8,'Radius',2);
 
end

        %% Funcion que segmenta la imagen  

        function [outImage] = segmentation(app,I)

                [m, n] = size(I);
                level = thresh_impl(app,I);     
                I_Bin = imbinarize(I,level);   
                K = medfilt2(I_Bin);
                se = strel('disk', 4);
                hairs = imbothat(K,se);      
                woHair = K;
            for i = 1:m
                for j =  1:n
                    if hairs(i, j) == 1
                woHair(i,j) = 1;
                    end
                end
            end
                woHair = imerode(woHair, se);
                woHair_Edge = edge(woHair,'Canny');
                se90 = strel('line', 2, 90);
                se0 = strel('line', 2, 0);
                woHair_Edge_Dil = imdilate(woHair_Edge, [se90 se0]);
                outImage = imfill(woHair_Edge_Dil, 'holes');
                outImage = bwareafilt(outImage, 1);
        end

         function [level] = thresh_impl(app,I)
            if ~isempty(I)
                I = im2uint8(I(:));
                num_bins = 256;
                counts = imhist(I,num_bins);
                level = otsu_impl(app,counts);
            else
                level = 0.0;
            end
         end
         
                 function [t] = otsu_impl(~, counts)
                num_bins = numel(counts);
                counts = double( counts(:) );
                p = counts / sum(counts);
                omega = cumsum(p);
                mu = cumsum(p .* (1:num_bins)');
                mu_t = mu(length(mu));
                sigma_b_squared = (mu_t * omega - mu).^2 ./ (omega .* (1 - omega));
                maxval = max(sigma_b_squared);
                isfinite_maxval = isfinite(maxval);
            if isfinite_maxval
                idx = mean(find(sigma_b_squared == maxval));
                t = (idx - 1) / (num_bins - 1);
            else
                t = 0.0;
            end
                 end
                 
                function Img = filtrado(app,I)
            
                I_G = rgb2gray(I);
                I_G = im2double(I_G);
    
                Fft_I = fft2(log(I_G + 0.01));
                Fil_I = Butterworth_HighPassFilter(app,I_G, 500, 3);
                temp = Fft_I .* Fil_I;
                h = real(ifft2(temp));
                h = exp(h);
                Img = ifftshow(app,h);
                end
                
                        function [out] = Butterworth_HighPassFilter(~,I, d, n)
                h = size(I, 1);
                w = size(I, 2);
                [x, y] = meshgrid(-floor(w / 2):floor(w - 1) / 2, -floor(h / 2):floor(h - 1) / 2);
                out = 1 ./ (1 + (d ./ (x .^ 2 + y .^ 2) .^ 0.5) .^ (2 * n));
        end
        
        function [I] = ifftshow(~,f)
                fabs = abs(f);
                fmax = max(fabs(:));
                I = (fabs / fmax);
        end
        
        end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CargarImagenButton
        function CargarImagenButtonPushed(app, event)
            
            [filename,app.pathname] = uigetfile({'*.bmp'},'Select dermatoscopic image');            % Permite elegir imagen a clasificar
            
            if app.pathname == 0 
              
                % Si se presiona cancelar al seleccionar la imagen la interfaz grafica de seleccion de imagen es cargada nuevamente 
                
            app.CargarImagenButton.ButtonPushedFcn(app,event)                                    
                
            else    
                
                % Si se selecciona una imgen se procede con su analisis
                
            app.I = imread(fullfile(app.pathname, filename));                                       % Define variable to image data    
            
            imshow(app.I,'Parent',app.Imagen_Original);                                         % Grafica Imagen a clasificar en interfaz grafica 
            
            % Se recorta la imagen médica para eliminar los bordes negros presentes debido al dermatoscopio utilizado.
            
            ventana = [80 14 590 550];                                                          % Vector con valores que definen la ventana 
            app.Icrp = imcrop(app.I,ventana);                                                        % Imagen recortada que será utilizada 
            
            %% Segmentacion de imagen
            
            Img = filtrado (app,app.I);
            
            [Img] = segmentation(app,Img); 
            I_R = app.I(:,:,1);
            I_G = app.I(:,:,2);
            I_B = app.I(:,:,3);
            Img_seg = uint8(Img);
            I_R = I_R .* Img_seg;            
            I_G = I_G .* Img_seg;
            I_B = I_B .* Img_seg;
            Img_seg = cat(3, I_R, I_G, I_B);
            Image_Seg = Img_seg;
            imshow(Image_Seg,'parent',app.Imagen_Segmentada);            
                                                          
            end                                   
        end

        % Button pushed function: ProcesarButton
        function ProcesarButtonPushed(app, event)
            
            if isempty(app.Icrp) == 0    
                
            %% Extracción de información de los tres canales RGB si luedo de verificar que la variable I.Crp no este vacia
            
            % En esta sección se extrae la información de cada uno de los canales RGB y se la almacena para poder utilizarla posteriormente.
            
            Img = app.Icrp;                                                  % Definir imagen recortada como Img
 
            % Extraer datos de canal Red (Rojo)
            R = Img;                                                     % Definir variable canal R
            R(:,:,2) = 0; R(:,:,3) = 0;                                  % Eliminar información de canales G y B
            R_G = rgb2gray(R);                                           % Transformar a escala de Grises
            R_G_D = im2double(R_G);                                      % Transformación de formato Imagen a numérico de doble precisión
            imshow(R,'Parent',app.Canal_R); 
             
            % Extraer datos de canal Green (Verde)
            G = Img;                                                     % Definir variable canal G                                                                     
            G(:,:,1) = 0; G(:,:,3) = 0;                                  % Eliminar información de canales R y B  
            G_G = rgb2gray(G);                                           % Transformar a escala de Grises
            G_G_D = im2double(G_G);                                      % Transformación de formato Imagen a numérico de doble precisión
            imshow(G,'Parent',app.Canal_G); 
             
            % Extraer datos de canal Blue (Azul)
            B = Img;                                                     % Definir variable canal B                                                            
            B(:,:,1) = 0; B(:,:,2) = 0;                                  % Eliminar información de canales R y G
            B_G = rgb2gray(B);                                           % Transformar a escala de Grises
            B_G_D = im2double(B_G);                                      % Transformación de formato Imagen a numérico de doble precisión
            imshow(B,'Parent',app.Canal_B); 
            
            %% Extracción de características mediante operador LBP
 
            % Para la extracción de datos se ha reutilizado el código creado por Daniel Quero. Este ha sido aplicado para cada uno de los canales RGB.
 
            [ DataLBP_R , ImageLBP_R] = getLBP(app,R_G_D);                   % Canal R
            imshow(ImageLBP_R,'Parent',app.LBPCanal_R);
            
            [ DataLBP_G , ImageLBP_G] = getLBP(app,G_G_D);                   % Canal G
            imshow(ImageLBP_G,'Parent',app.LBPCanal_G);
            
            [ DataLBP_B , ImageLBP_B] = getLBP(app,B_G_D);                   % Canal B
            imshow(ImageLBP_B,'Parent',app.LBPCanal_B);
            
            app.DataLBP = cat(2, DataLBP_R, DataLBP_G, DataLBP_B)';          % Concatena datos LBP de 3 canales RGB en una columna 
                
            else 
                
                % En caso de que la variable I.crp significa que no se cargo una imagen -> Se activa el boton "Cargar Imagen" automaticamente 
                
                app.CargarImagenButton.ButtonPushedFcn(app,event)
                
            end
        end

        % Button pushed function: ClasificarButton
        function ClasificarButtonPushed(app, event)
            if isempty(app.DataLBP) == 0
            
            %% Clasificacion utilizando red neuronal Entrenada, luego de verificar que se hayan extraido las caracteristicas LBP
            
            load("Trained_NN.mat","NN")                                     % Se carga la red Neuronal Entrenada
            
            Clasificacion = NN(app.DataLBP);                                % Se realiza la clasificacion de acuerdo a los datos extraidos           
            Clasificacion = round(Clasificacion);                           % Se aproxima el valor al entero mas cercano
            
            % Se define como informar el resultado del clasificador
            
            if Clasificacion(1,1) == 1 && Clasificacion(2,1) == 0 && Clasificacion(3,1) == 0
                % Cuando el diagnostico es Nevus Comun -> [1;0;0]
                
                app.Salida.Value = "Nevus Común";
                
            elseif Clasificacion(1,1) == 0  && Clasificacion(2,1) == 1 && Clasificacion(3,1) == 0
                % Cuando el diagnostico es Nevus Atipico -> [0;1;0]
                
                app.Salida.Value = "Nevus Atípico";
                
            elseif Clasificacion(1,1) == 0  && Clasificacion(2,1) == 0  && Clasificacion(3,1) == 1
                % Cuando el diagnostico es Melanoma -> [0;0;1]
               
                app.Salida.Value = "Melanoma";
                
            end
                            
            else
                
                % En caso de que las caracteristicas LBP no se encuentren -> Se activa el boton "Procesar" automaticamente 
                
                app.ProcesarButton.ButtonPushedFcn(app,event)
                
            end
        end

        % Button pushed function: LimpiarButton
        function LimpiarButtonPushed(app, event)
         
        app.pathname = 0;
        app.Icrp = [];
        app.DataLBP = [];
        cla (app.Canal_B,'reset');
        cla (app.Canal_G,'reset');
        cla (app.Canal_R,'reset');
        cla (app.Imagen_Original,'reset');
        cla (app.Imagen_Segmentada,'reset');
        cla (app.LBPCanal_B,'reset');
        cla (app.LBPCanal_G,'reset');
        cla (app.LBPCanal_R,'reset');
        app.Salida.Value = " ";
        
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Clasificador = uifigure('Visible', 'off');
            app.Clasificador.Name = 'Clasificador';
            app.Imagen_Original = uiaxes(app.Clasificador);
            title(app.Imagen_Original, 'Imagen Original')
            xlabel(app.Imagen_Original, '')
            ylabel(app.Imagen_Original, ' ')
            app.Canal_R = uiaxes(app.Clasificador);
            title(app.Canal_R, 'Canal R - Rojo')
            xlabel(app.Canal_R, '')
            ylabel(app.Canal_R, ' ')
            app.Canal_G = uiaxes(app.Clasificador);
            title(app.Canal_G, 'Canal G - Verde')
            xlabel(app.Canal_G, '')
            ylabel(app.Canal_G, ' ')
            app.Canal_B = uiaxes(app.Clasificador);
            title(app.Canal_B, 'Canal B - Azul')
            xlabel(app.Canal_B, '')
            ylabel(app.Canal_B, ' ')
            app.Imagen_Segmentada = uiaxes(app.Clasificador);
            title(app.Imagen_Segmentada, 'Imagen Segmentada')
            xlabel(app.Imagen_Segmentada, '')
            ylabel(app.Imagen_Segmentada, ' ')
            app.LBPCanal_R = uiaxes(app.Clasificador);
            title(app.LBPCanal_R, 'Imagen LBP Canal R - Rojo')
            xlabel(app.LBPCanal_R, '')
            ylabel(app.LBPCanal_R, ' ')
            app.LBPCanal_G = uiaxes(app.Clasificador);
            title(app.LBPCanal_G, 'Imagen LBP Canal G - Verde')
            xlabel(app.LBPCanal_G, '')
            ylabel(app.LBPCanal_G, ' ')
            app.LBPCanal_B = uiaxes(app.Clasificador);
            title(app.LBPCanal_B, 'Imagen LBP Canal B - Azul')
            xlabel(app.LBPCanal_B, '')
            ylabel(app.LBPCanal_B, ' ')
            app.Salida = uieditfield(app.Clasificador, 'text');
            app.Salida.Editable = 'off';
            app.DiagnsticoButton = uibutton(app.Clasificador, 'push');
            app.DiagnsticoButton.Text = 'Diagnóstico';
            app.OpcionesButtonGroup = uibuttongroup(app.Clasificador);
            app.OpcionesButtonGroup.Title = 'Opciones';
            app.CargarImagenButton = uibutton(app.OpcionesButtonGroup, 'push');
            app.CargarImagenButton.ButtonPushedFcn = createCallbackFcn(app, @CargarImagenButtonPushed, true);
            app.CargarImagenButton.Text = 'Cargar Imagen';
            app.ProcesarButton = uibutton(app.OpcionesButtonGroup, 'push');
            app.ProcesarButton.ButtonPushedFcn = createCallbackFcn(app, @ProcesarButtonPushed, true);
            app.ProcesarButton.Text = 'Procesar';
            app.ClasificarButton = uibutton(app.OpcionesButtonGroup, 'push');
            app.ClasificarButton.ButtonPushedFcn = createCallbackFcn(app, @ClasificarButtonPushed, true);
            app.ClasificarButton.Text = 'Clasificar';
            app.LimpiarButton = uibutton(app.OpcionesButtonGroup, 'push');
            app.LimpiarButton.ButtonPushedFcn = createCallbackFcn(app, @LimpiarButtonPushed, true);
            app.LimpiarButton.Text = 'Limpiar';
            % Show the figure after all components are created
            app.Clasificador.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = APP1_Clasificador

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Clasificador)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Clasificador)
        end
    end
end